classdef AutoQ4MS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
        TabGroup                  matlab.ui.container.TabGroup
        MethodsTab                matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        SQLFileButton             matlab.ui.control.Button
        CreateMS2LibraryButton    matlab.ui.control.Button
        DeleteLockFileButton      matlab.ui.control.Button
        LogFileButton             matlab.ui.control.Button
        RunmethodButton           matlab.ui.control.Button
        EditmethodButton          matlab.ui.control.Button
        AddnewmethodButton        matlab.ui.control.Button
        DeletemethodButton        matlab.ui.control.Button
        RefreshButton             matlab.ui.control.Button
        MethodTable               matlab.ui.control.Table
        DashboardTab              matlab.ui.container.Tab
        RefreshButton_2           matlab.ui.control.Button
        MethodDropDown            matlab.ui.control.DropDown
        MethodDropDownLabel       matlab.ui.control.Label
        EndDatePicker             matlab.ui.control.DatePicker
        EnddateDatePickerLabel    matlab.ui.control.Label
        StartDatePicker           matlab.ui.control.DatePicker
        StartdateDatePickerLabel  matlab.ui.control.Label
        PolarityTabGroup          matlab.ui.container.TabGroup
        PositivTab                matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        UIAxes_Pos_RT             matlab.ui.control.UIAxes
        UIAxes_Pos_MA             matlab.ui.control.UIAxes
        UIAxes_Pos_Int            matlab.ui.control.UIAxes
        NegativeTab               matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        UIAxes_Neg_RT             matlab.ui.control.UIAxes
        UIAxes_Neg_MA             matlab.ui.control.UIAxes
        UIAxes_Neg_Int            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function updateMethodTable(app)
            folder = fullfile('data', 'Import', 'methods');
            if ~exist(folder, 'dir')
                mkdir(folder);
            end
        
            files = dir(fullfile(folder, '*.mat'));
            data = {};
            row = 1;  % separate row counter. 
        
            for i = 1:numel(files)
                if strcmp(files(i).name, 'Parameters.mat')
                    continue;  % Skip
                end
        
                methodName = erase(files(i).name, '.mat');
                taskOn = false;
        
                try
                    S = load(fullfile(folder, files(i).name), 'Parameters');
                    if isfield(S.Parameters, 'TaskManager') && isfield(S.Parameters.TaskManager, 'On')
                        taskOn = S.Parameters.TaskManager.On;
                    end
                catch
                    warning('Could not load method %s.', methodName);
                end
        
                % Only enter valid method
                data{row, 1} = methodName;
                data{row, 2} = taskOn;
                row = row + 1;
            end
        
            app.MethodTable.Data = data;
        end

        
        function closeAppAndQuit(app)
            delete(app);
            
            if ~usejava('desktop')
                quit
            end

        end 

       
        function populateMethodDropdown(app)
            % Define the folder where method .mat files are stored
            methodFolder = fullfile('data', 'Import', 'methods');
            
            % Get a list of all .mat files in the folder
            files = dir(fullfile(methodFolder, '*.mat'));
            
            % Initialize cell array to store method names
            methodNames = {};
        
            % Loop through each file in the folder
            for i = 1:length(files)
                file = files(i).name;
        
                % Remove the file extension to get the method name
                [~, methodName] = fileparts(file);
        
                % Add the method name to the list
                methodNames{end+1} = methodName;
            end
        
            % If no method files were found, display a default message
            if isempty(methodNames)
                methodNames = {'<no methods found>'};
            end
        
            % Update the dropdown menu in the app with the method names
            app.MethodDropDown.Items = methodNames;
        end


        function toggleUI(app, tf)
            % tf = true  → UI active
            % tf = false → UI disabled (locked)
        
            state = 'on';
            
            % If tf is false, disable UI and show wait cursor
            if ~tf
                state = 'off';
                app.UIFigure.Pointer = 'watch';  % Show hourglass / loading cursor
            else
                % If tf is true, enable UI and show normal pointer
                app.UIFigure.Pointer = 'arrow';  % Reset to default cursor
            end
        
            % Toggle all relevant UI controls at once
            app.MethodDropDown.Enable        = state;
            app.StartDatePicker.Enable      = state;
            app.EndDatePicker.Enable        = state;
            app.RefreshButton_2.Enable      = state;
            app.RunmethodButton.Enable      = state;
            app.EditmethodButton.Enable     = state;
            app.AddnewmethodButton.Enable   = state;
            app.DeletemethodButton.Enable   = state;
            app.RefreshButton.Enable        = state;
            app.SQLFileButton.Enable        = state;
            app.LogFileButton.Enable        = state;
            app.DeleteLockFileButton.Enable        = state;
            app.CreateMS2LibraryButton.Enable = state;
        end


    end
    
    
    methods (Access = public)
        
        function checkLogsAndNotify(app, path)
            % checkLogsAndNotify checks for .log files in the given folder.
            % If log files are found, it shows a modal dialog attached to the App window.
        
            logsPath = fullfile(path, 'logs');
        
            if ~isfolder(logsPath)
                return;
            end
        
            logFiles = dir(fullfile(logsPath, '*.log'));
            
            if isempty(logFiles)
                return;
            end
        
            % Create modal confirmation dialog
            d = uiconfirm(app.UIFigure, ...
                'There are log files in the "logs" folder. Would you like to view them?', ...
                'Log Files Found', ...
                'Options', {'Open Folder', 'Later'}, ...
                'DefaultOption', 'Later', ...
                'CloseFcn', @(src, event) ...
                    handleChoice(event.SelectedOption, logsPath));
            
            % Inline handling as nested function
            function handleChoice(choice, logsPathInner)
                if strcmp(choice, 'Open Folder')
                    if isfolder(logsPathInner)
                        winopen(logsPathInner);
                    end
                end
            end
        end

        function updateFileCountButton(app, button, folderPath, extension, labelPrefix)
            if isfolder(folderPath)
                files = dir(fullfile(folderPath, ['*' extension]));
                count = numel(files);
            else
                count = 0;
            end

            % Text setzen
            button.Text = sprintf('%s:      %d', labelPrefix, count);

            % Farbe anpassen
            if count == 0
                button.BackgroundColor = [0.7 1 0.7];  % grün
            else
                button.BackgroundColor = [1 0.6 0.6];  % rot
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Welcome AutoQ4MS is launched');
            % Disable the UI during startup
                toggleUI(app, false);
                
            
                % Get the full path of this file and change the working directory
                thisFile = mfilename('fullpath');
                [thisPath, ~, ~] = fileparts(thisFile);
                cd(thisPath);
                setup();
                
                % Set up the method table:
                % - First column: method name (char)
                % - Second column: checkbox (logical)
                app.MethodTable.ColumnFormat = {'char', 'logical'};
                app.MethodTable.ColumnEditable = [false, true];  % Only checkbox editable
                app.MethodTable.ColumnWidth = {'1x', 80};        % Auto width + fixed width for checkbox

                updateFileCountButton(app, app.LogFileButton, fullfile(pwd, 'logs'), '.log', 'Log files');
                updateFileCountButton(app, app.SQLFileButton, fullfile(pwd, 'src','database','failed'), '.sql', 'SQL files');
            
                % Optionally populate the table initially
                updateMethodTable(app);
                checkLogsAndNotify(app, thisPath);  % Uses default logs folder
            
                % Re-enable the UI after setup is complete
                toggleUI(app, true);
                % Define what happens when the app window is closed
                app.UIFigure.CloseRequestFcn = @(src, event) closeAppAndQuit(app);
        end

        % Callback function
        function MethodTableCellEdit(app, event)
            % Disable UI while processing the change
            toggleUI(app, false);
        
            % Get the edited cell's row and column
            row = event.Indices(1);
            col = event.Indices(2);
        
            % We are only interested in column 2 (Task Active checkbox)
            if col ~= 2
                return;
            end
        
            % Extract method name and new checkbox value
            methodName = app.MethodTable.Data{row, 1};
            taskActive = app.MethodTable.Data{row, 2};  % new value from user input
        
            try
                % Load the method's parameter file
                load(fullfile(pwd,'data', 'Import', 'methods', [methodName, '.mat']), 'Parameters');
                
                % Create method object from parameters
                m = method(Parameters, methodName);
        
                % Toggle task status on or off based on checkbox
                if taskActive
                    m.turnTaskon();
                else
                    m.turnTaskoff();
                end
        
                % Reload the table to reflect any changes
                app.updateMethodTable();
        
            catch ME
                % Display an alert if something went wrong
                uialert(app.UIFigure, ...
                    sprintf('Error while updating task status:\n%s', ME.message), ...
                    'Task Update Error');
            end 
        
            % Re-enable the UI
            toggleUI(app, true);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            toggleUI(app, false);
            updateFileCountButton(app, app.LogFileButton, fullfile(pwd, 'logs'), '.log', 'Log files');
            updateFileCountButton(app, app.SQLFileButton, fullfile(pwd, 'src','database','failed'), '.sql', 'SQL files');
            app.updateMethodTable();
            toggleUI(app, true)
        end

        % Button pushed function: DeletemethodButton
        function DeletemethodButtonPushed(app, event)
            toggleUI(app, false);  % Disable UI while processing
            
            % Check if a row is selected
            if isempty(app.MethodTable.Selection)
                uialert(app.UIFigure, 'Please select a method.', 'No Selection');
                toggleUI(app, true);
                return;
            end
            
            
            % Get selected row and corresponding method name (column 1)
            row = app.MethodTable.Selection(1);
            methodName = app.MethodTable.Data{row, 1};
            
            % Confirmation dialog before deletion
            selection = uiconfirm(app.UIFigure, ...
                ['Do you really want to delete the method "' methodName '"?'], ...
                'Delete Method', ...
                'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2);
            
            if strcmp(selection, 'Yes')
                try
                    % Load method parameters
                    load(fullfile('data', 'Import', 'methods', [methodName, '.mat']), 'Parameters');
                    app.DeleteLockFileButtonPushed([]);
                    
                    % Create method object and delete it
                    m = method(Parameters, methodName);
                    m.delmethod();
            
                    % Refresh the table after deletion
                    app.updateMethodTable();
            
                    % Inform the user
                    uialert(app.UIFigure, ['Method "' methodName '" has been deleted.'], 'Deleted');
                    
                catch ME
                    % Display an error alert if deletion fails
                    uialert(app.UIFigure, ...
                        ['Error while deleting the method: ', newline, ME.message], ...
                        'Error');
                end
            end
            
            
            % Re-enable the UI
            toggleUI(app, true);

        end

        % Button pushed function: AddnewmethodButton
        function AddnewmethodButtonPushed(app, event)
            toggleUI(app, false);  % Disable UI during method creation
            try
                % Load default parameter template
                load(fullfile('data', 'Import', 'methods', 'Parameters.mat'), 'Parameters');
            
                % Create new method
                % No name is passed → user will be prompted via dialog
                m = method(Parameters);
            
                % Refresh the method table after creation
                app.updateMethodTable();
            
                % Notify the user
                uiconfirm(app.UIFigure, ...
                    ['Method "' m.Name '" created successfully.'], ...
                    'Success', ...
                    'Options', {'OK'}, ...
                    'Icon', 'success');  % shows a green checkmark icon

            
            catch ME
                % Ignore specific error if user canceled the creation dialog
                if ~strcmp(ME.identifier, 'MATLAB:unassignedOutputs')
                    uialert(app.UIFigure, ...
                        ['Error while creating the method: ' newline ME.message], ...
                        'Error');
                end
            end
            
            % Re-enable the UI
            toggleUI(app, true);

        end

        % Callback function: EditmethodButton, MethodTable
        function EditmethodButtonPushed(app, event)
            toggleUI(app, false);  % Disable UI while editing
        
            % Ensure a method is selected
            if isempty(app.MethodTable.Selection)
                uialert(app.UIFigure, 'Please select a method.', 'No Selection');
                toggleUI(app, true);
                return;
            end
        
            % Get selected method name from the table (column 1)
            row = app.MethodTable.Selection(1);
            methodName = app.MethodTable.Data{row, 1};
        
            try
                
                load(fullfile('data', 'Import', 'methods', ['Parameters', '.mat']), 'Parameters');
                % save(fullfile('data', 'Import', 'methods', [methodName, '.mat']), 'Parameters');

                % Load the existing method
                currentmethod = method(Parameters, methodName);
        
                % Open the parameter GUI for editing
                currentmethod.Parameters = editParametersGUI(currentmethod.Parameters);

                if isempty(currentmethod.Parameters)
                    %No parameters have been updated
                    uiconfirm(app.UIFigure, ...
                    'No parameters have been updated.', ...
                    'No update', ...
                    'Options', {'OK'}, ...
                    'Icon', 'success');

                else
                % Update task Manager
                currentmethod.taskmanagerupdate()
                % Save the updated method
                currentmethod.savemethod()

                % Refresh the method table
                app.updateMethodTable();
                
                % Show success message with green checkmark instead of red alert
                uiconfirm(app.UIFigure, ...
                    ['Method "' methodName '" has been updated.'], ...
                    'Edit Complete', ...
                    'Options', {'OK'}, ...
                    'Icon', 'success');

                end
        
            catch ME
                % Show error alert if editing failed
                uialert(app.UIFigure, ...
                    ['Error during editing: ' newline ME.message], ...
                    'Error');
            end
        
            toggleUI(app, true);  % Re-enable UI
        end

        % Button pushed function: RunmethodButton
        function RunmethodButtonPushed(app, event)

            toggleUI(app, false);  % Disable UI while running method
        
            % Check if a method is selected
            if isempty(app.MethodTable.Selection)
                uialert(app.UIFigure, 'Please select a method.', 'No Selection');
                toggleUI(app, true);
                return;
            end
        
            % Get the selected method name
            row = app.MethodTable.Selection(1);
            methodName = app.MethodTable.Data{row, 1};
        
            % Path to the method's .bat file
            batFilePath = fullfile('bat', [methodName '_run_processing.bat']);
        
            % Check if the .bat file exists
            if isfile(batFilePath)
                try
                    % Execute the batch file once
                    system(['start "" "' batFilePath '"']);
                catch ME
                    uialert(app.UIFigure, ...
                        ['Error while starting the method:' newline ME.message], ...
                        'Execution Failed');
                end
            else
                % Inform user if .bat file is missing
                uialert(app.UIFigure, ...
                    ['The .bat file for "' methodName '" was not found.'], ...
                    'File Not Found');
            end
        
            toggleUI(app, true);  % Re-enable UI
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            % Lock the UI during processing
            toggleUI(app, false);
            
            % Update dropdown with available methods
            populateMethodDropdown(app);
           
            
            % Optionally show status (commented out)
            % app.StatusLabel.Text = "Loading plots...";
            drawnow;
            
            try
                % Read selected values
                method = app.MethodDropDown.Value;
                startDate = app.StartDatePicker.Value;
                endDate = app.EndDatePicker.Value;
            
                % Determine selected polarity
                if app.PolarityTabGroup.SelectedTab == app.PositivTab
                    polarity = '+';
                    axesStruct = struct( ...
                        'RT', app.UIAxes_Pos_RT, ...
                        'MA', app.UIAxes_Pos_MA, ...
                        'Intensity', app.UIAxes_Pos_Int ...
                    );
                else
                    polarity = '-';
                    axesStruct = struct( ...
                        'RT', app.UIAxes_Neg_RT, ...
                        'MA', app.UIAxes_Neg_MA, ...
                        'Intensity', app.UIAxes_Neg_Int ...
                    );
                end
            
                % Load method parameters
                Parameters = load(fullfile('data', 'Import', 'methods', [method, '.mat']));
                Parameters = Parameters.Parameters;
            
                % Call the plot function
                generateISPlots(polarity, startDate, endDate, axesStruct, Parameters);
            
            catch ME
                % Show error alert if something fails during plotting
                uialert(app.UIFigure, 'No data found', 'Plotting Error');
            end
            
            % Re-enable the UI after completion
            toggleUI(app, true);


        end

        % Selection change function: PolarityTabGroup
        function PolarityTabGroupSelectionChanged(app, event)
            toggleUI(app, false);
            RefreshButton_2Pushed(app, event);
            toggleUI(app, true)
        end

        % Button pushed function: LogFileButton
        function LogFileButtonPushed(app, event)
            toggleUI(app, false);
            folderPath = fullfile(pwd, 'logs');  % Adjust the path if necessary
            if isfolder(folderPath)
                winopen(folderPath);  % Open the folder in Windows Explorer
            else
                uialert(app.UIFigure, ...
                    sprintf('Folder not found:%s%s', newline, folderPath), ...
                    'Error');
            end
            toggleUI(app, true)
        end

        % Button pushed function: SQLFileButton
        function SQLFileButtonPushed(app, event)
            toggleUI(app, false);
            folderPath = fullfile(pwd, 'src','database','failed');
            if isfolder(folderPath)
                winopen(folderPath);  % Open the folder in Windows File Explorer
            else
                uialert(app.UIFigure, ...
                    sprintf('Folder not found:%s%s', newline, folderPath), ...
                    'Error');
            end
            toggleUI(app, true)
        end

        % Button pushed function: DeleteLockFileButton
        function DeleteLockFileButtonPushed(app, event)
            toggleUI(app, false);
            % Ensure a method is selected
            if isempty(app.MethodTable.Selection)
                uialert(app.UIFigure, 'Please select a method.', 'No Selection');
                return;
            end
            
            % Get the method name from the selected row in the table
            row = app.MethodTable.Selection(1);
            methodName = app.MethodTable.Data{row, 1};
            
            % Construct the path to the corresponding lock file
            lockfile = fullfile(tempdir, [methodName, '.lock']);
            
            % Check if the lock file exists and delete it
            if isfile(lockfile)
                delete(lockfile);
                uialert(app.UIFigure, ...
                    sprintf('Lock file for "%s" has been deleted.', methodName), ...
                    'Success', 'Icon', 'success');
            else
                uialert(app.UIFigure, ...
                    sprintf('No lock file found for "%s".', methodName), ...
                    'Not Found', 'Icon', 'info');
            end
            toggleUI(app, true)

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
                toggleUI(app, false);
            % Populate the dropdown menu with available methods
                selectedTab = event.NewValue;
                if selectedTab == app.DashboardTab
                    populateMethodDropdown(app);
            
                    % Preselect a method if needed
                    if isempty(app.MethodDropDown.Items)
                        populateMethodDropdown(app);  % Retry if list is still empty
                    end
                
                    % Set a default method (e.g., first one in the list)
                    app.MethodDropDown.Value = app.MethodDropDown.Items{1};
                
                    % Set a default date range (last 30 days up to tomorrow)
                    app.StartDatePicker.Value = datetime('now') - days(30);
                    app.EndDatePicker.Value   = datetime('now') + days(1);
                
                    % Automatically trigger a refresh
                    RefreshButton_2Pushed(app, []);

                end


                if selectedTab == app.MethodsTab
                    % Set up the method table:
                    % - First column: method name (char)
                    % - Second column: checkbox (logical)
                    app.MethodTable.ColumnFormat = {'char', 'logical'};
                    app.MethodTable.ColumnEditable = [false, true];  % Only checkbox editable
                    app.MethodTable.ColumnWidth = {'1x', 80};        % Auto width + fixed width for checkbox
    
                    updateFileCountButton(app, app.LogFileButton, fullfile(pwd, 'logs'), '.log', 'Log files');
                    updateFileCountButton(app, app.SQLFileButton, fullfile(pwd, 'src','database','failed'), '.sql', 'SQL files');
                
                    % Optionally populate the table initially
                    updateMethodTable(app);
                end
                toggleUI(app, true)
        end

        % Button pushed function: CreateMS2LibraryButton
        function CreateMS2LibraryButtonPushed(app, event)
            toggleUI(app, false);
            % Check if a method is selected
            if isempty(app.MethodTable.Selection)
                uialert(app.UIFigure, 'Please select a method.', 'No Selection');
                toggleUI(app, true);
                return;
            end
        
            % Get the selected method name
            row = app.MethodTable.Selection(1);
            methodName = app.MethodTable.Data{row, 1};

            % Load the method's parameter file
            load(fullfile(pwd,'data', 'Import', 'methods', [methodName, '.mat']), 'Parameters');
            try
                createMS2lib(Parameters);
            catch e
                if ~isempty(findall(0,'Type','figure','-and','Tag','TMWWaitbar'))
                    delete(findall(0,'Type','figure','-and','Tag','TMWWaitbar'));
                end
                if ~isempty(findall(0,'Type','figure','-and','Tag','MS2Waitbar'))
                    delete(findall(0,'Type','figure','-and','Tag','MS2Waitbar'));
                end
                uialert(app.UIFigure, e.message, 'Error Creating MS2 Library', 'Icon', 'error')
               
            end

            setup()
            
            toggleUI(app, true)
        end

        % Cell edit callback: MethodTable
        function UITableCellEdit(app, event)
            toggleUI(app, false);
            try 
                row = event.Indices;
                bool = event.NewData;
                % load method 
                methodName = app.MethodTable.Data{row, 1};
                load(fullfile(pwd,'data', 'Import', 'methods', [methodName, '.mat']), 'Parameters');
                Parameters.TaskManager.On =bool;
                save(fullfile(pwd,'data', 'Import', 'methods', [methodName, '.mat']), 'Parameters');
                currentmethod = method(Parameters, methodName);
                
                currentmethod.taskmanagerupdate()   
            catch e
                uialert(app.UIFigure, e.message, 'Error Creating Task', 'Icon', 'error')
            end

            toggleUI(app, true)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MethodsTab = uitab(app.TabGroup);
            app.MethodsTab.Title = 'Methods';
            app.GridLayout2 = uigridlayout(app.MethodsTab);
            app.MethodTable = uitable(app.GridLayout2);
            app.MethodTable.ColumnName = {'Method'; 'Task activ'};
            app.MethodTable.RowName = {};
            app.MethodTable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.MethodTable.DoubleClickedFcn = createCallbackFcn(app, @EditmethodButtonPushed, true);
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeletemethodButton = uibutton(app.GridLayout2, 'push');
            app.DeletemethodButton.ButtonPushedFcn = createCallbackFcn(app, @DeletemethodButtonPushed, true);
            app.DeletemethodButton.Text = 'Delete method';
            app.AddnewmethodButton = uibutton(app.GridLayout2, 'push');
            app.AddnewmethodButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewmethodButtonPushed, true);
            app.AddnewmethodButton.Text = 'Add new method';
            app.EditmethodButton = uibutton(app.GridLayout2, 'push');
            app.EditmethodButton.ButtonPushedFcn = createCallbackFcn(app, @EditmethodButtonPushed, true);
            app.EditmethodButton.Text = 'Edit method';
            app.RunmethodButton = uibutton(app.GridLayout2, 'push');
            app.RunmethodButton.ButtonPushedFcn = createCallbackFcn(app, @RunmethodButtonPushed, true);
            app.RunmethodButton.Text = 'Run method';
            app.LogFileButton = uibutton(app.GridLayout2, 'push');
            app.LogFileButton.ButtonPushedFcn = createCallbackFcn(app, @LogFileButtonPushed, true);
            app.LogFileButton.Text = 'Logs: ...';
            app.DeleteLockFileButton = uibutton(app.GridLayout2, 'push');
            app.DeleteLockFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLockFileButtonPushed, true);
            app.DeleteLockFileButton.Text = 'Delete Lock File';
            app.CreateMS2LibraryButton = uibutton(app.GridLayout2, 'push');
            app.CreateMS2LibraryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMS2LibraryButtonPushed, true);
            app.CreateMS2LibraryButton.Text = 'Create MS2 Library';
            app.SQLFileButton = uibutton(app.GridLayout2, 'push');
            app.SQLFileButton.ButtonPushedFcn = createCallbackFcn(app, @SQLFileButtonPushed, true);
            app.SQLFileButton.Text = 'SQLs: ...';
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.PolarityTabGroup = uitabgroup(app.DashboardTab);
            app.PolarityTabGroup.SelectionChangedFcn = createCallbackFcn(app, @PolarityTabGroupSelectionChanged, true);
            app.PositivTab = uitab(app.PolarityTabGroup);
            app.PositivTab.Title = 'Positive';
            app.GridLayout3 = uigridlayout(app.PositivTab);
            app.UIAxes_Pos_Int = uiaxes(app.GridLayout3);
            title(app.UIAxes_Pos_Int, 'Title')
            xlabel(app.UIAxes_Pos_Int, 'X')
            ylabel(app.UIAxes_Pos_Int, 'Y')
            zlabel(app.UIAxes_Pos_Int, 'Z')
            app.UIAxes_Pos_MA = uiaxes(app.GridLayout3);
            title(app.UIAxes_Pos_MA, 'Title')
            xlabel(app.UIAxes_Pos_MA, 'X')
            ylabel(app.UIAxes_Pos_MA, 'Y')
            zlabel(app.UIAxes_Pos_MA, 'Z')
            app.UIAxes_Pos_RT = uiaxes(app.GridLayout3);
            title(app.UIAxes_Pos_RT, 'Title')
            xlabel(app.UIAxes_Pos_RT, 'X')
            ylabel(app.UIAxes_Pos_RT, 'Y')
            zlabel(app.UIAxes_Pos_RT, 'Z')
            app.NegativeTab = uitab(app.PolarityTabGroup);
            app.NegativeTab.Title = 'Negative';
            app.GridLayout4 = uigridlayout(app.NegativeTab);
            app.UIAxes_Neg_Int = uiaxes(app.GridLayout4);
            title(app.UIAxes_Neg_Int, 'Title')
            xlabel(app.UIAxes_Neg_Int, 'X')
            ylabel(app.UIAxes_Neg_Int, 'Y')
            zlabel(app.UIAxes_Neg_Int, 'Z')
            app.UIAxes_Neg_MA = uiaxes(app.GridLayout4);
            title(app.UIAxes_Neg_MA, 'Title')
            xlabel(app.UIAxes_Neg_MA, 'X')
            ylabel(app.UIAxes_Neg_MA, 'Y')
            zlabel(app.UIAxes_Neg_MA, 'Z')
            app.UIAxes_Neg_RT = uiaxes(app.GridLayout4);
            title(app.UIAxes_Neg_RT, 'Title')
            xlabel(app.UIAxes_Neg_RT, 'X')
            ylabel(app.UIAxes_Neg_RT, 'Y')
            zlabel(app.UIAxes_Neg_RT, 'Z')
            app.StartdateDatePickerLabel = uilabel(app.DashboardTab);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartDatePicker = uidatepicker(app.DashboardTab);
            app.EnddateDatePickerLabel = uilabel(app.DashboardTab);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EndDatePicker = uidatepicker(app.DashboardTab);
            app.MethodDropDownLabel = uilabel(app.DashboardTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.DashboardTab);
            app.RefreshButton_2 = uibutton(app.DashboardTab, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'topMTSapp.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoQ4MS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end